classdef utfr_lapsim_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainTabGroup                   matlab.ui.container.TabGroup
        CarDataTab                     matlab.ui.container.Tab
        SuspensionDataPanel            matlab.ui.container.Panel
        WeightEditFieldLabel           matlab.ui.control.Label
        MassEF                         matlab.ui.control.NumericEditField
        kgLabel                        matlab.ui.control.Label
        TrackEditFieldLabel            matlab.ui.control.Label
        TrackEF                        matlab.ui.control.NumericEditField
        WheelbaseEditFieldLabel        matlab.ui.control.Label
        WheelbaseEF                    matlab.ui.control.NumericEditField
        COGHeightEditFieldLabel        matlab.ui.control.Label
        COGHeightEF                    matlab.ui.control.NumericEditField
        WeightDistributionEditFieldLabel  matlab.ui.control.Label
        FRWeightDistEF                 matlab.ui.control.NumericEditField
        BrakeBiasEditFieldLabel        matlab.ui.control.Label
        FRBrakeBiasEF                  matlab.ui.control.NumericEditField
        mLabel                         matlab.ui.control.Label
        mLabel_2                       matlab.ui.control.Label
        mLabel_3                       matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        TireDataPanel                  matlab.ui.container.Panel
        LatCoefficientEditFieldLabel   matlab.ui.control.Label
        LatTCEF                        matlab.ui.control.NumericEditField
        LongCoefficientEditFieldLabel  matlab.ui.control.Label
        LongTCEF                       matlab.ui.control.NumericEditField
        WheelRadiusEditFieldLabel      matlab.ui.control.Label
        WheelRadiusEF                  matlab.ui.control.NumericEditField
        mLabel_4                       matlab.ui.control.Label
        AerodynamicDataPanel           matlab.ui.container.Panel
        CoefficientofLiftEditFieldLabel  matlab.ui.control.Label
        CoLiftEF                       matlab.ui.control.NumericEditField
        CoefficientofDragEditFieldLabel  matlab.ui.control.Label
        CoDragEF                       matlab.ui.control.NumericEditField
        FrontalAreaEditFieldLabel      matlab.ui.control.Label
        FAreaEF                        matlab.ui.control.NumericEditField
        m2Label                        matlab.ui.control.Label
        GeneralDataPanel               matlab.ui.container.Panel
        AirDensityEditFieldLabel       matlab.ui.control.Label
        AirDensityEF                   matlab.ui.control.NumericEditField
        kgm3Label                      matlab.ui.control.Label
        AirTemperatureEditFieldLabel   matlab.ui.control.Label
        AirTempEF                      matlab.ui.control.NumericEditField
        CLabel                         matlab.ui.control.Label
        PowertrainTypeButtonGroup      matlab.ui.container.ButtonGroup
        ICButton                       matlab.ui.control.RadioButton
        EVButton                       matlab.ui.control.RadioButton
        ConfigurationButtonGroup       matlab.ui.container.ButtonGroup
        WD4Button                      matlab.ui.control.RadioButton
        WD2Button_2                    matlab.ui.control.RadioButton
        PowertrainDataPanel            matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        ICTab                          matlab.ui.container.Tab
        TvRPMTable                     matlab.ui.control.Table
        FinalDriveEditFieldLabel       matlab.ui.control.Label
        ICGearFD                       matlab.ui.control.NumericEditField
        stEditFieldLabel               matlab.ui.control.Label
        ICGear1                        matlab.ui.control.NumericEditField
        ndEditFieldLabel               matlab.ui.control.Label
        ICGear2                        matlab.ui.control.NumericEditField
        rdEditFieldLabel               matlab.ui.control.Label
        ICGear3                        matlab.ui.control.NumericEditField
        thEditFieldLabel               matlab.ui.control.Label
        ICGear4                        matlab.ui.control.NumericEditField
        thEditField_2Label             matlab.ui.control.Label
        ICGear5                        matlab.ui.control.NumericEditField
        ConfiguredforTRX450Label       matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        FuelDropDownLabel              matlab.ui.control.Label
        FuelDropDown                   matlab.ui.control.DropDown
        ICMaxSpeed                     matlab.ui.control.NumericEditField
        MaximumSpeedLabel              matlab.ui.control.Label
        kmhLabel                       matlab.ui.control.Label
        DrivetrainEfficiencyEditFieldLabel  matlab.ui.control.Label
        DREff                          matlab.ui.control.NumericEditField
        MaximumRPMEditFieldLabel       matlab.ui.control.Label
        ICMaxRPM                       matlab.ui.control.NumericEditField
        UIEngineAxis                   matlab.ui.control.UIAxes
        EVTab                          matlab.ui.container.Tab
        SaveCarButton                  matlab.ui.control.Button
        LoadCarButton                  matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        v10Label                       matlab.ui.control.Label
        SelectedCarDropDownLabel       matlab.ui.control.Label
        SelectedCarDropDown            matlab.ui.control.DropDown
        CarNameEditFieldLabel          matlab.ui.control.Label
        CarNameEF                      matlab.ui.control.EditField
        Switch                         matlab.ui.control.Switch
        TrackDataTab                   matlab.ui.container.Tab
        TrackTable                     matlab.ui.control.Table
        AddRowButton                   matlab.ui.control.Button
        DrawTrackButton                matlab.ui.control.Button
        SaveTrackButton                matlab.ui.control.Button
        LoadTrackButton                matlab.ui.control.Button
        TrackNameEditFieldLabel        matlab.ui.control.Label
        TrackNameEditField             matlab.ui.control.EditField
        DeleteRowButton                matlab.ui.control.Button
        TrackMap                       matlab.ui.control.UIAxes
        SimulationParametersTab        matlab.ui.container.Tab
        SweepPanel                     matlab.ui.container.Panel
        Parameter1DropDownLabel        matlab.ui.control.Label
        P1DD                           matlab.ui.control.DropDown
        Parameter2DropDownLabel        matlab.ui.control.Label
        P2DD                           matlab.ui.control.DropDown
        P1_1                           matlab.ui.control.NumericEditField
        P1_2                           matlab.ui.control.NumericEditField
        ToLabel                        matlab.ui.control.Label
        HighLabel                      matlab.ui.control.Label
        LowLabel                       matlab.ui.control.Label
        P2_1                           matlab.ui.control.NumericEditField
        P2_2                           matlab.ui.control.NumericEditField
        ToLabel_2                      matlab.ui.control.Label
        HighLabel_2                    matlab.ui.control.Label
        LowLabel_2                     matlab.ui.control.Label
        ofPointsEditFieldLabel         matlab.ui.control.Label
        numP1                          matlab.ui.control.NumericEditField
        ofPointsEditField_2Label       matlab.ui.control.Label
        numP2                          matlab.ui.control.NumericEditField
        Sweep2P                        matlab.ui.control.Button
        SingleRunTrackListBoxLabel     matlab.ui.control.Label
        SingleRunTrackListBox          matlab.ui.control.ListBox
        PointsCalculationsPanel        matlab.ui.container.Panel
        EnduranceEditFieldLabel        matlab.ui.control.Label
        EnduranceEditField             matlab.ui.control.NumericEditField
        AutocrossEditFieldLabel        matlab.ui.control.Label
        AutocrossEditField             matlab.ui.control.NumericEditField
        SkidpadEditFieldLabel          matlab.ui.control.Label
        SkidpadEditField               matlab.ui.control.NumericEditField
        AccelEditFieldLabel            matlab.ui.control.Label
        AccelEditField                 matlab.ui.control.NumericEditField
        EnterFastesttimesrecordedfortherelevanttracklayoutLabel  matlab.ui.control.Label
        EntermostrecentFastestTimeLabel  matlab.ui.control.Label
        EnduranceTrackDropDownLabel    matlab.ui.control.Label
        EnduranceTrackDropDown         matlab.ui.control.DropDown
        AutocrossTrackDropDownLabel    matlab.ui.control.Label
        AutocrossTrackDropDown         matlab.ui.control.DropDown
        secLabel                       matlab.ui.control.Label
        secLabel_2                     matlab.ui.control.Label
        secLabel_3                     matlab.ui.control.Label
        secLabel_4                     matlab.ui.control.Label
        SaveRunButton                  matlab.ui.control.Button
        LoadedCarsListBoxLabel         matlab.ui.control.Label
        LoadedCarsListBox              matlab.ui.control.ListBox
        SingleRunButton                matlab.ui.control.Button
        UpdateDataButton               matlab.ui.control.Button
        ResultsAnalysisTab             matlab.ui.container.Tab
        ViewOptionsPanel               matlab.ui.container.Panel
        XAxisDropDownLabel             matlab.ui.control.Label
        XAxisDropDown                  matlab.ui.control.DropDown
        YAxisDropDownLabel             matlab.ui.control.Label
        YAxisDropDown                  matlab.ui.control.DropDown
        ZAxisDropDownLabel             matlab.ui.control.Label
        ZAxisDropDown                  matlab.ui.control.DropDown
        ListBoxLabel                   matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
        XAxisUnits                     matlab.ui.control.DropDown
        YAxisUnits                     matlab.ui.control.DropDown
        ZAxisUnits                     matlab.ui.control.DropDown
        LoadedDatasetsListBoxLabel     matlab.ui.control.Label
        LDSlistbox                     matlab.ui.control.ListBox
        LoadDataButton                 matlab.ui.control.Button
        SaveGraphButton                matlab.ui.control.Button
        ComparisonsPanel               matlab.ui.container.Panel
        PlotGraphButton                matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Car properties
        ICGearing                       % IC Car Gearing
        ICDRe                           % IC Car Drivetrain Efficiency
        EVGearing                       % EV Car Gearing
        EVDRe                           % EV Car Drivetrain Efficinecy
        
        % Simulation Parameters
        cl                              % CoL*FA*rho*1/2
        cd                              % CoD*FA*rho*1/2
        dist_step                       % Step interval for calculations
        accel_data                      % Stores data from 0-max accel
        decel_data                      % Stores data from max-0 decel
        SGearing                        % Final ratios for simulation
        SSpeeds                         % Final gearing max speeds for simulations
                   % [Dist Time Vel ALong ALat RPM Gear] Most recent laptime data generated
        
        % Program Data
        
        savedcars                       % Cell Array storing all currently loaded cars
        selectedcar                     % Selected base car data for simulation (or sweeps)
        
        savedtracks                     % Cell Array storing all currently loaded tracks
        selectedtracks                  % Selected Tracks for simulation
        
        savedresults                    % Cell Array for storing all created and loaded data
        selectedresults                  % Currently Selected dataset(s)
    end
    
    methods (Access = private)
        
        function ICMaxspeed(app)
            app.ICMaxSpeed.Value = app.ICMaxRPM.Value*((3*pi())/25)*app.WheelRadiusEF.Value/(app.ICGearing(5)*app.ICGearing(6));
        end
        
        function upengineplot(app)
            plot(app.UIEngineAxis,app.TvRPMTable.Data(:,1),app.TvRPMTable.Data(:,2),"LineWidth",2)
            title(app.UIEngineAxis,'Torque vs RPM')
            xlabel(app.UIEngineAxis,'RPM')
            ylabel(app.UIEngineAxis, 'Torque [Nm]')
        end
        
        function initialize_data(app)
            app.ICGearing = [app.ICGear1.Value
                             app.ICGear2.Value
                             app.ICGear3.Value
                             app.ICGear4.Value
                             app.ICGear5.Value
                             app.ICGearFD.Value];
        end
        
        function updateresults(app,rundata,filename)
            
            if isempty(app.savedresults)
                app.savedresults = containers.Map();
                app.savedresults(filename)= rundata;
                app.LDSlistbox.Items = keys(app.savedresults);
            else
                app.savedresults(filename)= rundata;
                app.LDSlistbox.Items = keys(app.savedresults);
            end
            
        end
        
        function var = varselect(app,value)
            switch value
                case 'distance'
                    var = app.selectedresults.distance;
                case 'time'
                    var = app.selectedresults.time;
                case 'velocity'
                    var = app.selectedresults.velocity;
                case 'along'
                    var = app.selectedresults.along;
                case 'alat'
                    var = app.selectedresults.alat;
                case 'rpm'
                    var = app.selectedresults.rpm;
                case 'gear'
                    var = app.selectedresults.gear;
            end
        end
        
        function axis_mod = unitchange(~,axis,value)
            switch value
                case 'distance'
                    axis.Items = {'m'};
                case 'time'
                    axis.Items = {'sec'
                                  'min'};
                case 'velocity'
                    axis.Items = {'m/s'
                                  'kph'};
                case 'along'
                    axis.Items = {'m/s^2'
                                  "g's"};
                case 'alat'
                    axis.Items = {'m/s^2'
                                  "g's"};
                case 'rpm'
                    axis.Items = {'RPM'};
                case 'gear'
                    axis.Items = {'-'};
            end
            axis_mod = axis;
        end
        
        function [axis_mod1,axis_mod2] = axiscontrol(~,axis1,axis2,state)
            axis1.Enable = state;
            axis1.Editable = state;
            axis2.Enable = state;
            axis2.Editable = state;
            axis_mod1 = axis1;
            axis_mod2 = axis2;
        end
    end
    methods (Access = public,Static)
        function output = cangvel(input, condition1)
            if condition1 == "rad/s"
                output = input*30/pi;
            elseif condition1 == "rpm"
                output = input*pi/30;
            end
        end
    end
    
    methods (Access = public)
        
        function results = func9(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Table Generation
            app.TvRPMTable.Data = [1000 18.53283694
                                1500 18.53283694
                                2000 18.53283694
                                2500 15.08896936
                                3000 24.47137652
                                3500 26.32950042
                                4000 23.49072638
                                4500 20.51501654
                                5000 15.5225542
                                5500 17.76599646
                                6000 24.86971056
                                6500 30.61654002
                                7000 35.61645926
                                7500 38.57400138
                                8000 39.21637942
                                8500 38.36669956
                                9000 37.23514888
                                9500 35.86619762
                                10000 34.31367104
                                10500 32.73213034
                               ];
            app.TrackTable.Data = [ 20  0
                                    22	26
                                    15	0
                                    6	33
                                    116	0
                                    55	54
                                    46	0
                                    8	29
                                    22	0
                                    13	20
                                    97	0
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                                    36	27];
            app.P1DD.ItemsData = [1,2,3,4,5,6,7,8,9,10,11];
            app.P2DD.ItemsData = [1,2,3,4,5,6,7,8,9,10,11];
            % Variable Generation
            initialize_data(app)
            ICMaxspeed(app)
            upengineplot(app)
        end

        % Value changed function: ICGearFD
        function ICGearFDValueChanged(app, event)
            value = app.ICGearFD.Value;
            app.ICGearing(6) = value;
            ICMaxspeed(app)
        end

        % Value changed function: ICGear5
        function ICGear5ValueChanged(app, event)
            value = app.ICGear5.Value;
            app.ICGearing(5) = value;
            ICMaxspeed(app)
        end

        % Value changed function: ICGear4
        function ICGear4ValueChanged(app, event)
            value = app.ICGear4.Value;
            app.ICGearing(4) = value;
            ICMaxspeed(app)
        end

        % Value changed function: ICGear3
        function ICGear3ValueChanged(app, event)
            value = app.ICGear3.Value;
            app.ICGearing(3) = value;
            ICMaxspeed(app)
        end

        % Value changed function: ICGear2
        function ICGear2ValueChanged(app, event)
            value = app.ICGear2.Value;
            app.ICGearing(2) = value;
            ICMaxspeed(app)
        end

        % Value changed function: ICGear1
        function ICGear1ValueChanged(app, event)
            value = app.ICGear1.Value;
            app.ICGearing(1) = value;
            ICMaxspeed(app)
        end

        % Cell edit callback: TvRPMTable
        function TvRPMTableCellEdit(app, event)
            upengineplot(app)
        end

        % Callback function: AddRowButton, AirDensityEF, MassEF, 
        % SingleRunTrackListBox
        function AddTrackRow(app, event)
            app.TrackTable.Data = [app.TrackTable.Data; [0,0]];
            
            % Here you want to prompt and save the data, as well as concat
            % the newly created row.
            % Eg: from save track
            
            prompt = {'Enter row length','Enter row radius'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'0','0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            % Getting the input is easy
            rls = answer(1);
            rrs = answer(2);
            row_len = str2double(rls);
            row_rad = str2double(rrs);
            % add row to table.
            app.TrackTable.Data = [app.TrackTable.Data ; [row_len, row_rad]];
            
            % set track names and assoc data (TODO: WHY, there seems no
            % need here.
            % These do not seem necesssary for the program to work.
            
            %namescell = keys(app.savedtracks);
            %app.SingleRunTrackListBox.Items = namescell;
            %app.SingleRunTrackListBox.ItemsData = app.savedtracks;
        end

        % Button pushed function: SingleRunButton
        function SingleRun(app, event)
            initialize_data(app);
            rundata = laptime_calc(app,app.selectedcar);
            [filename,filepath] = uiputfile('*.mat','Save Run Data:');
            FileName = fullfile(filepath,filename);
            rundata = rundata.Name(filename(1:end-4));
            save(FileName,'rundata','-v7.3')
            updateresults(app,rundata,filename)
        end

        % Button pushed function: DrawTrackButton
        function DrawTrack(app, event)
            x = 0;
            y = 0;
            xlist = [0];
            ylist = [0];
            theta = 0;
            TrackData = app.TrackTable.Data;
            % loop through each row of the column
            for i = 1:length(TrackData(:,1))
                % if it needs to draw a straight line
                if TrackData(i,2) == 0
                    % find line endpoint
                    x2 = x + TrackData(i,1)*cos(theta);
                    y2 = y + TrackData(i,1)*sin(theta);
                    % draw the line
                    xlist = [xlist, x2];
                    ylist = [ylist, y2];
                    % Update starting point
                    x = x2;
                    y = y2;
                % if it needs to draw a curve
                else
                    % Calculate Total angle change
                    dtheta = TrackData(i,1)/TrackData(i,2);
                    % Calculate Final Direction
                    theta2 = theta + dtheta;
                    % Calculate Origin of radius
                    if TrackData(i,2) < 0
                        ptheta = theta - pi/2;
                    elseif TrackData(i,2) > 0
                        ptheta = theta + pi/2;
                    else
                        disp("ERROR: Invalid Radius Assignment")
                    end
                    xorigin = x + abs(TrackData(i,2))*cos(ptheta);
                    yorigin = y + abs(TrackData(i,2))*sin(ptheta);
                    % Generate list of angles every degree
                    thetalist = linspace(theta,theta2,round(abs((theta2-theta)*180/pi)));
                    thetalist = thetalist - pi/2;
                    xCir = TrackData(i,2)*cos(thetalist) + xorigin;
                    yCir = TrackData(i,2)*sin(thetalist) + yorigin;
                    for j = 1:length(xCir)
                        xlist = [xlist, xCir(j)];
                        ylist = [ylist, yCir(j)];
                    end
                    x = xCir(end);
                    y = yCir(end);
                    theta = theta2;
                end
                plot(app.TrackMap,xlist,ylist,'Color','k','LineWidth',3,'LineStyle','-')
            end
        end

        % Button pushed function: LoadTrackButton
        function LoadTrack(app, event)
            [file,searchpath] = uigetfile('*.mat','MultiSelect','on');
            tracknm = "";
            if isempty(app.savedtracks)
               app.savedtracks = containers.Map;
            end
            oldpath = path; 
            path(oldpath,searchpath)
            if exist(file,"var")
                    uialert('No data selected!','Load Error')
            elseif ischar('file')
                    load(file,'track');
                    trackname = track.name;
                    tracknm = trackname;
                    keyset = keys(app.savedtracks);
                    if ismember(trackname,keyset)
                        selection = uiconfirm(app.UIFigure,'There is already a track with this name. Confirm overwrite?','Save Caution');
                        if selection == 'OK'
                            app.savedtracks(trackname) = track;
                        end
                    else
                        app.savedtracks(trackname) = track;
                    end
            elseif iscell(file)
                    N = length(file);
                    for i = 1:N
                        load(file(i),'track')
                        tracknm = track.name;
                        keyset = keys(app.savedtracks);
                        if ismember(carname,keyset)
                            selection = uiconfirm(app.UIFigure,'There is already a track with this name. Confirm overwrite?','Save Caution');
                            if selection == 'OK' %#ok<BDSCA> 
                                app.savedtracks(tracknm) = track;
                            end
                        else
                            app.savedtracks(tracknm) = track;
                        end
                    end
            else
                uialert(app.UIFigure,'Unexpected Error Occured! (Error 001)','Logic Error')
            end
            path(oldpath)
            app.selectedtracks.Items = keys(app.savedtracks);
            track = app.savedtracks(tracknm);
            
            % Originally, used track.name on initialization
            % Initialize with app.selectedtrack instead
            app.TrackNameEditField.Value = track.name;
            
            % TODO: Figure out why this does not work
            %app.TrackNameEditField.Value = app.selectedtracks(tracknm);
            
            % load radiuses and lengths
            trads = track.trackradius;
            trlens = track.tracklength;
            %app.TrackTable.Data{1} = track.trackradius;
            %app.TrackTable.Data{2} = track.tracklength;
            TrackData = app.TrackTable.Data;
            for i = 1:length(trads)
                TrackData = [TrackData; [(trads(i)), (trlens(i))]];
            end
            
        end

        % Button pushed function: SaveCarButton
        function SaveCar(app, event)
            namecheck = app.CarNameEF.Value;
            namecheck = namecheck(~isspace(namecheck));
            if isempty(namecheck)
                uialert(app.UIFigure,"Car must have a name before saving!","Save Error")
            else
                
                if app.WD2Button_2.Value   
                    WD = 2;
                elseif app.WD4Button.Value
                    WD = 4;
                end
                
                if app.EVButton.Value   
                    VT = "EV";
                elseif app.ICButton.Value
                    VT = "IC";
                end
                
                pd = uiprogressdlg(app.UIFigure,"Title",'Car Save',"Message",'Creating Datastruct...','Indeterminate','on');
                pause(1)
                car = CAR(app.COGHeightEF.Value,app.FRBrakeBiasEF.Value, ...
                app.FRWeightDistEF.Value,app.MassEF.Value,app.TrackEF.Value, ...
                app.WheelbaseEF.Value,app.LatTCEF.Value,app.LongTCEF.Value, ...
                app.WheelRadiusEF.Value,app.CoDragEF.Value,app.CoLiftEF.Value, ...
                app.FAreaEF.Value,app.DREff.Value, ...
                [app.ICGear1.Value,app.ICGear2.Value,app.ICGear3.Value, ...
                app.ICGear4.Value,app.ICGear5.Value,app.ICGearFD.Value], ...
                app.ICMaxRPM.Value,WD,VT,app.AirDensityEF.Value,app.TvRPMTable.Data, ...
                app.CarNameEF.Value);
                pd.Message = 'Calculating Accel Values...';
                car = acceleration_calc(car);
                pause(1)
                pd.Message = 'Calculating Decel Values...';
                car = deceleration_calc(car);
                pause(1)
                pd.Message = 'Done.';
                pause(1)
                uisave('car')
                close(pd)
            end
        end

        % Button pushed function: LoadCarButton
        function LoadCar(app, event)
            [file,searchpath] = uigetfile('*.mat','MultiSelect','on');
            if isempty(app.savedcars)
               app.savedcars = containers.Map;
            end
            oldpath = path; 
            path(oldpath,searchpath)
            if exist(file,"var")
                    uialert('No data selected!','Load Error')
            elseif ischar('file')
                    load(file,'car')
                    carname = car.name;
                    keyset = keys(app.savedcars);
                    if ismember(carname,keyset)
                        selection = uiconfirm(app.UIFigure,'There is already a car with this name. Confirm overwrite?','Save Caution');
                        if selection == 'OK'
                            app.savedcars(carname) = car;
                        end
                    else
                        app.savedcars(carname) = car;
                    end
            elseif iscell(file)
                    N = length(file);
                    for i = 1:N
                        load(file(i),'car')
                        carname = car.name;
                        keyset = keys(app.savedcars);
                        if ismember(carname,keyset)
                            selection = uiconfirm(app.UIFigure,'There is already a car with this name. Confirm overwrite?','Save Caution');
                            if selection == 'OK' %#ok<BDSCA> 
                                app.savedcars(carname) = car;
                            end
                        else
                            app.savedcars(carname) = car;
                        end
                    end
            else
                uialert(app.UIFigure,'Unexpected Error Occured! (Error 001)','Logic Error')
            end
            path(oldpath)
            app.SelectedCarDropDown.Items = keys(app.savedcars);
            app.LoadedCarsListBox.Items = app.SelectedCarDropDown.Items;
            app.selectedcar = app.savedcars(app.SelectedCarDropDown.Value);
        end

        % Value changed function: SelectedCarDropDown
        function SelectCar(app, event)
            value = app.SelectedCarDropDown.Value;
            app.LoadedCarsListBox.Value = value;
            app.selectedcar = app.savedcars(value);
        end

        % Value changed function: LoadedCarsListBox
        function CarSelection(app, event)
            value = app.LoadedCarsListBox.Value;
            app.SelectedCarDropDown.Value = value;
            app.selectedcar = app.savedcars(value);
        end

        % Button pushed function: LoadDataButton
        function LoadData(app, event)
            [file,searchpath] = uigetfile('*.mat','MultiSelect','on');
            if isempty(app.savedresults)
               app.savedresults = containers.Map;
            end
            oldpath = path; 
            path(oldpath,searchpath)
            if exist(file,"var")
                    uialert('No data selected!','Load Error')
            elseif ischar('file')
                    load(file,'rundata')
                    dataname = rundata.name;
                    keyset = keys(app.savedresults);
                    if ismember(dataname,keyset)
                        selection = uiconfirm(app.UIFigure,'There is already a rundata with this name. Confirm overwrite?','Save Caution');
                        if selection == 'OK'
                            app.savedresults(dataname) = rundata;
                        end
                    else
                        app.savedresults(dataname) = rundata;
                    end
            elseif iscell(file)
                    N = length(file);
                    for i = 1:N
                        load(file(i),'rundata')
                        dataname = rundata.name;
                        keyset = keys(app.savedresults);
                        if ismember(dataname,keyset)
                            selection = uiconfirm(app.UIFigure,'There is already a rundata with this name. Confirm overwrite?','Save Caution');
                            if selection == 'OK' %#ok<BDSCA> 
                                app.savedresults(dataname) = rundata;
                            end
                        else
                            app.savedresults(dataname) = rundata;
                        end
                    end
            else
                uialert(app.UIFigure,'Unexpected Error Occured! (Error 001)','Logic Error')
            end
            path(oldpath)
            app.LDSlistbox.Items = keys(app.savedresults);
        end

        % Value changed function: Switch
        function CarDataViewSwitch(app, event)
            value = app.Switch.Value;
            if value == 0
                if isempty(app.SelectedCarDropDown.Items)
                    uialert(app.UIFigure,'No saved cars to use!','Display Error')
                    app.Switch.Value = 1;
                else
                    selectedkey = app.SelectedCarDropDown.Value;
                    app.selectedcar = app.savedcars(selectedkey);
                    app.CarNameEF.Value = app.selectedcar.name;
                    app.CarNameEF.Editable = "Off";
                    app.ICMaxRPM.Value = app.selectedcar.maxrpm;
                    app.ICMaxRPM.Editable = "Off";
                    app.DREff.Value = app.selectedcar.dreff;
                    app.DREff.Editable = "Off";
                    app.ICMaxSpeed.Value = app.selectedcar.maxspeed;
                    app.ICMaxSpeed.Editable = "Off";
                    app.ICGear1.Value = app.selectedcar.gears(1);
                    app.ICGear1.Editable = "Off";
                    app.ICGear2.Value = app.selectedcar.gears(2);
                    app.ICGear2.Editable = "Off";
                    app.ICGear3.Value = app.selectedcar.gears(3);
                    app.ICGear3.Editable = "Off";
                    app.ICGear4.Value = app.selectedcar.gears(4);
                    app.ICGear4.Editable = "Off";
                    app.ICGear5.Value = app.selectedcar.gears(5);
                    app.ICGear5.Editable = "Off";
                    app.ICGearFD.Value = app.selectedcar.gears(6);
                    app.ICGearFD.Editable = "Off";
                    app.TvRPMTable.Data = app.selectedcar.torquecurve;
                    set(app.TvRPMTable,'Enable','Off')
                    set(app.WD2Button_2,'Enable','Off')
                    set(app.WD4Button,'Enable','Off')
                    set(app.EVButton,'Enable','Off')
                    set(app.ICButton,'Enable','Off')
                    app.FAreaEF.Value = app.selectedcar.fa;
                    app.FAreaEF.Editable = "Off";
                    app.CoDragEF.Value = app.selectedcar.cod;
                    app.CoDragEF.Editable = "Off";
                    app.CoLiftEF.Value = app.selectedcar.col;
                    app.CoLiftEF.Editable = "Off";
                    app.WheelRadiusEF.Value = app.selectedcar.wr;
                    app.WheelRadiusEF.Editable = "Off";
                    app.LongTCEF.Value = app.selectedcar.longtcf;
                    app.LongTCEF.Editable = "Off";
                    app.LatTCEF.Value = app.selectedcar.lattcf;
                    app.LatTCEF.Editable = "Off";
                    app.FRBrakeBiasEF.Value = app.selectedcar.frbb;
                    app.FRBrakeBiasEF.Editable = "Off";
                    app.FRWeightDistEF.Value = app.selectedcar.frstwd;
                    app.FRWeightDistEF.Editable = "Off";
                    app.COGHeightEF.Value = app.selectedcar.cogheight;
                    app.COGHeightEF.Editable = "Off";
                    app.WheelbaseEF.Value = app.selectedcar.wb;
                    app.WheelbaseEF.Editable = "Off";
                    app.TrackEF.Value = app.selectedcar.track;
                    app.TrackEF.Editable = "Off";
                    app.MassEF.Value = app.selectedcar.mass;
                    app.MassEF.Editable = "Off";
                end
                
            else
                app.CarNameEF.Editable = "On";
                app.ICMaxRPM.Editable = "On";
                app.DREff.Editable = "On";
                app.ICMaxSpeed.Editable = "On";
                app.ICGear1.Editable = "On";
                app.ICGear2.Editable = "On";
                app.ICGear3.Editable = "On";
                app.ICGear4.Editable = "On";
                app.ICGear5.Editable = "On";
                app.ICGearFD.Editable = "On";
                set(app.TvRPMTable,'Enable','On')
                set(app.WD2Button_2,'Enable',"On")
                set(app.WD4Button,'Enable',"On")
                set(app.EVButton,'Enable',"On")
                set(app.ICButton,'Enable',"On")
                app.FAreaEF.Editable = "On";
                app.CoDragEF.Editable = "On";
                app.CoLiftEF.Editable = "On";
                app.WheelRadiusEF.Editable = "On";
                app.LongTCEF.Editable = "On";
                app.LatTCEF.Editable = "On";
                app.FRBrakeBiasEF.Editable = "On";
                app.FRWeightDistEF.Editable = "On";
                app.COGHeightEF.Editable = "On";
                app.WheelbaseEF.Editable = "On";
                app.TrackEF.Editable = "On";
                app.MassEF.Editable = "On";
            end
        end

        % Value changed function: ListBox
        function ResultsPlotAxisControl(app, event)
            value = app.ListBox.Value;
            switch value
                case '1'
                    [app.XAxisDropDown,app.XAxisUnits] = axiscontrol(app,app.XAxisDropDown,app.XAxisUnits,'on');
                    [app.YAxisDropDown,app.YAxisUnits] = axiscontrol(app,app.YAxisDropDown,app.YAxisUnits,'on');
                    [app.ZAxisDropDown,app.ZAxisUnits] = axiscontrol(app,app.ZAxisDropDown,app.ZAxisUnits,'off');
                case '2'
                    [app.XAxisDropDown,app.XAxisUnits] = axiscontrol(app,app.XAxisDropDown,app.XAxisUnits,'on');
                    [app.YAxisDropDown,app.YAxisUnits] = axiscontrol(app,app.YAxisDropDown,app.YAxisUnits,'on');
                    [app.ZAxisDropDown,app.ZAxisUnits] = axiscontrol(app,app.ZAxisDropDown,app.ZAxisUnits,'off');
                case '3'
                    [app.XAxisDropDown,app.XAxisUnits] = axiscontrol(app,app.XAxisDropDown,app.XAxisUnits,'on');
                    [app.YAxisDropDown,app.YAxisUnits] = axiscontrol(app,app.YAxisDropDown,app.YAxisUnits,'on');
                    [app.ZAxisDropDown,app.ZAxisUnits] = axiscontrol(app,app.ZAxisDropDown,app.ZAxisUnits,'on');
                case '4'
                    [app.XAxisDropDown,app.XAxisUnits] = axiscontrol(app,app.XAxisDropDown,app.XAxisUnits,'on');
                    [app.YAxisDropDown,app.YAxisUnits] = axiscontrol(app,app.YAxisDropDown,app.YAxisUnits,'on');
                    [app.ZAxisDropDown,app.ZAxisUnits] = axiscontrol(app,app.ZAxisDropDown,app.ZAxisUnits,'on');
            end
        end

        % Button pushed function: PlotGraphButton
        function ResultsPlot(app, event)
            value = app.ListBox.Value;
            if isempty(app.selectedresults)
                uialert(app.UIFigure,'No Data Selected!')
            else
                app.selectedresults = app.savedresults(app.LDSlistbox.Value);
                switch value
                    case '1'
                        var1 = varselect(app,app.XAxisDropDown.Value);
                        var2 = varselect(app,app.YAxisDropDown.Value);
                        plot(app.UIAxes2,var1,var2,"Color",'b',"LineWidth",2)
                        xlabel(app.UIAxes2,app.XAxisDropDown.Value)
                        ylabel(app.UIAxes2,app.YAxisDropDown.Value)
                        titlename = strcat(app.XAxisDropDown.Value, ' vs ',app.YAxisDropDown.Value);
                        title(app.UIAxes2,titlename)
                    case '2'
                    case '3'
                    case '4'
                end
            end
            
            
        end

        % Value changed function: LDSlistbox
        function SelectData(app, event)
            value = app.LDSlistbox.Value;
            app.selectedresults = app.savedresults(value);
        end

        % Value changed function: XAxisDropDown
        function XAxisDataChange(app, event)
            value = app.XAxisDropDown.Value;
            app.XAxisUnits = unitchange(app,app.XAxisUnits,value);
        end

        % Value changed function: YAxisDropDown
        function YAxisDataChange(app, event)
            value = app.YAxisDropDown.Value;
            app.YAxisUnits = unitchange(app,app.YAxisUnits,value);
        end

        % Value changed function: ZAxisDropDown
        function ZAxisDataChange(app, event)
            value = app.ZAxisDropDown.Value;
            app.ZAxisUnits = unitchange(app,app.ZAxisUnits,value);
        end

        % Button pushed function: SaveTrackButton
        function SaveTrack(app, event)
            
            prompt = {'Enter Track Name'};
            dlgtitle = 'Input';
            dims = [1 50];
            definput = {'Interlagos'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
                
            namecheck = answer;
            namecheck = namecheck(~isspace(namecheck));
            
            lenlist = [0];
            radlist = [0];
            
            TrackData = app.TrackTable.Data;
            for i = 1:length(TrackData(:,1))
                lenlist = [lenlist, TrackData(i, 1)];
                radlist = [radlist, TrackData(i, 2)];
                
            end
            
            % Save track here
            if isempty(namecheck)
                uialert(app.UIFigure,"ERR: Track Name Empty.","Save Error")
            else
                %put save track logic here
                pause(1)
                %car = CAR(app.COGHeightEF.Value,app.FRBrakeBiasEF.Value, ...
                %track = TRACK(answer{0}, app.TrackTable.Data);
                track = TRACK(answer{1}, radlist, lenlist);
                %aesthetics
                pd = uiprogressdlg(app.UIFigure,"Title",'Saving Track',"Message",'Creating Tract Class struct...','Indeterminate','on');
                pause(1)
                pause(1)
                pd.Message = 'Done.';
                pause(1)
                uisave('track')
                close(pd)
            end
        end

        % Button pushed function: Sweep2P
        function P2sweep(app, event)
            if app.P1_2.Value > app.P1_1.Value
                uialert(app.UIFigure,"Lower bound cannot exceed Upper Bound! (Parameter 1)","Logic Error")
            elseif app.P2_2.Value > app.P2_1.Value
                uialert(app.UIFigure,"Lower bound cannot exceed Upper Bound! (Parameter 2)","Logic Error")
            else
                input1 = [app.P1DD.Value,app.P1_2.Value,app.P1_1.Value,app.numP1.Value];
                input2 = [app.P2DD.Value,app.P2_2.Value,app.P2_1.Value,app.numP1.Value];
                rundata = psweep(app,app.selectedcar,input1,input2,pd);
                p1info = {app.P1DD.Items(app.P1DD.Value),app.P1_2.Value,app.P1_1.Value,app.numP1.Value};
                p2info = {app.P2DD.Items(app.P2DD.Value),app.P2_2.Value,app.P2_1.Value,app.numP2.Value};
                datainfo = {p1info,p2info};
                [filename,filepath] = uiputfile('*.mat','Save Run Data:');
                FileName = fullfile(filepath,filename);
                save(FileName,'rundata','datainfo','-v7.3')
                updateresults(app,rundata,filename)
            end
        end

        % Value changed function: TrackNameEditField
        function TrackNameEditFieldValueChanged(app, event)

            app.TrackNameEditField.Value = app.selectedtrack;
        end

        % Button pushed function: UpdateDataButton
        function UpdateDataButtonPushed(app, event)
            app.AddTrackRow(event);
        end

        % Button pushed function: DeleteRowButton
        function DeleteRowButtonPushed(app, event)
             % Here, you delete the bottommost row.
             dat = app.TrackTable.Data;
             if size(dat,1) > 1; app.TrackTable.Data(end,:) = []; end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainTabGroup = uitabgroup(app.UIFigure);
            app.CarDataTab = uitab(app.MainTabGroup);
            app.CarDataTab.Title = 'Car Data';
            app.SuspensionDataPanel = uipanel(app.CarDataTab);
            app.SuspensionDataPanel.Title = 'Suspension Data';
            app.WeightEditFieldLabel = uilabel(app.SuspensionDataPanel);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.MassEF = uieditfield(app.SuspensionDataPanel, 'numeric');
            app.MassEF.ValueChangedFcn = createCallbackFcn(app, @AddTrackRow, true);
            app.MassEF.Tooltip = {'Vehicle Weight (Including Driver!)'};
            app.MassEF.Value = 226;
            app.kgLabel = uilabel(app.SuspensionDataPanel);
            app.kgLabel.Text = 'kg';
            app.TrackEditFieldLabel = uilabel(app.SuspensionDataPanel);
            app.TrackEditFieldLabel.Text = 'Track';
            app.TrackEF = uieditfield(app.SuspensionDataPanel, 'numeric');
            app.TrackEF.Value = 0.8;
            app.WheelbaseEditFieldLabel = uilabel(app.SuspensionDataPanel);
            app.WheelbaseEditFieldLabel.Text = 'Wheelbase';
            app.WheelbaseEF = uieditfield(app.SuspensionDataPanel, 'numeric');
            app.WheelbaseEF.Value = 1.2;
            app.COGHeightEditFieldLabel = uilabel(app.SuspensionDataPanel);
            app.COGHeightEditFieldLabel.Text = 'COG Height';
            app.COGHeightEF = uieditfield(app.SuspensionDataPanel, 'numeric');
            app.COGHeightEF.Value = 0.27;
            app.WeightDistributionEditFieldLabel = uilabel(app.SuspensionDataPanel);
            app.WeightDistributionEditFieldLabel.Text = 'Weight Distribution';
            app.FRWeightDistEF = uieditfield(app.SuspensionDataPanel, 'numeric');
            app.FRWeightDistEF.Value = 50;
            app.BrakeBiasEditFieldLabel = uilabel(app.SuspensionDataPanel);
            app.BrakeBiasEditFieldLabel.Text = 'Brake Bias';
            app.FRBrakeBiasEF = uieditfield(app.SuspensionDataPanel, 'numeric');
            app.FRBrakeBiasEF.Value = 55;
            app.mLabel = uilabel(app.SuspensionDataPanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.SuspensionDataPanel);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.SuspensionDataPanel);
            app.mLabel_3.Text = 'm';
            app.Label = uilabel(app.SuspensionDataPanel);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.SuspensionDataPanel);
            app.Label_2.Text = '%';
            app.TireDataPanel = uipanel(app.CarDataTab);
            app.TireDataPanel.Title = 'Tire Data';
            app.LatCoefficientEditFieldLabel = uilabel(app.TireDataPanel);
            app.LatCoefficientEditFieldLabel.Text = 'Lat. Coefficient';
            app.LatTCEF = uieditfield(app.TireDataPanel, 'numeric');
            app.LatTCEF.Value = 1.2;
            app.LongCoefficientEditFieldLabel = uilabel(app.TireDataPanel);
            app.LongCoefficientEditFieldLabel.Text = 'Long. Coefficient';
            app.LongTCEF = uieditfield(app.TireDataPanel, 'numeric');
            app.LongTCEF.Value = 1.2;
            app.WheelRadiusEditFieldLabel = uilabel(app.TireDataPanel);
            app.WheelRadiusEditFieldLabel.Text = 'Wheel Radius';
            app.WheelRadiusEF = uieditfield(app.TireDataPanel, 'numeric');
            app.WheelRadiusEF.Value = 0.229;
            app.mLabel_4 = uilabel(app.TireDataPanel);
            app.mLabel_4.Text = 'm';
            app.AerodynamicDataPanel = uipanel(app.CarDataTab);
            app.AerodynamicDataPanel.Title = 'Aerodynamic Data';
            app.CoefficientofLiftEditFieldLabel = uilabel(app.AerodynamicDataPanel);
            app.CoefficientofLiftEditFieldLabel.Text = 'Coefficient of Lift';
            app.CoLiftEF = uieditfield(app.AerodynamicDataPanel, 'numeric');
            app.CoLiftEF.Value = 1.2;
            app.CoefficientofDragEditFieldLabel = uilabel(app.AerodynamicDataPanel);
            app.CoefficientofDragEditFieldLabel.Text = 'Coefficient of Drag';
            app.CoDragEF = uieditfield(app.AerodynamicDataPanel, 'numeric');
            app.CoDragEF.Value = 0.8;
            app.FrontalAreaEditFieldLabel = uilabel(app.AerodynamicDataPanel);
            app.FrontalAreaEditFieldLabel.Text = 'Frontal Area';
            app.FAreaEF = uieditfield(app.AerodynamicDataPanel, 'numeric');
            app.FAreaEF.Value = 1;
            app.m2Label = uilabel(app.AerodynamicDataPanel);
            app.m2Label.Text = 'm^2';
            app.GeneralDataPanel = uipanel(app.CarDataTab);
            app.GeneralDataPanel.Title = 'General Data';
            app.AirDensityEditFieldLabel = uilabel(app.GeneralDataPanel);
            app.AirDensityEditFieldLabel.Text = 'Air Density';
            app.AirDensityEF = uieditfield(app.GeneralDataPanel, 'numeric');
            app.AirDensityEF.ValueChangedFcn = createCallbackFcn(app, @AddTrackRow, true);
            app.AirDensityEF.Value = 1.225;
            app.kgm3Label = uilabel(app.GeneralDataPanel);
            app.kgm3Label.Text = 'kg/m^3';
            app.AirTemperatureEditFieldLabel = uilabel(app.GeneralDataPanel);
            app.AirTemperatureEditFieldLabel.Text = 'Air Temperature';
            app.AirTempEF = uieditfield(app.GeneralDataPanel, 'numeric');
            app.AirTempEF.Value = 20;
            app.CLabel = uilabel(app.GeneralDataPanel);
            app.CLabel.Text = 'C';
            app.PowertrainTypeButtonGroup = uibuttongroup(app.GeneralDataPanel);
            app.PowertrainTypeButtonGroup.Title = 'Powertrain Type';
            app.ICButton = uiradiobutton(app.PowertrainTypeButtonGroup);
            app.ICButton.Text = 'IC';
            app.ICButton.Value = true;
            app.EVButton = uiradiobutton(app.PowertrainTypeButtonGroup);
            app.EVButton.Text = 'EV';
            app.ConfigurationButtonGroup = uibuttongroup(app.GeneralDataPanel);
            app.ConfigurationButtonGroup.Title = 'Configuration';
            app.WD4Button = uiradiobutton(app.ConfigurationButtonGroup);
            app.WD4Button.Text = '4WD';
            app.WD2Button_2 = uiradiobutton(app.ConfigurationButtonGroup);
            app.WD2Button_2.Text = '2WD';
            app.WD2Button_2.Value = true;
            app.PowertrainDataPanel = uipanel(app.CarDataTab);
            app.PowertrainDataPanel.Title = 'Powertrain Data';
            app.TabGroup2 = uitabgroup(app.PowertrainDataPanel);
            app.ICTab = uitab(app.TabGroup2);
            app.ICTab.Title = 'IC';
            app.TvRPMTable = uitable(app.ICTab);
            app.TvRPMTable.ColumnName = {'RPM'; 'Torque [Nm]'};
            app.TvRPMTable.RowName = {};
            app.TvRPMTable.ColumnSortable = true;
            app.TvRPMTable.ColumnEditable = true;
            app.TvRPMTable.CellEditCallback = createCallbackFcn(app, @TvRPMTableCellEdit, true);
            app.FinalDriveEditFieldLabel = uilabel(app.ICTab);
            app.FinalDriveEditFieldLabel.Text = 'Final Drive';
            app.ICGearFD = uieditfield(app.ICTab, 'numeric');
            app.ICGearFD.ValueChangedFcn = createCallbackFcn(app, @ICGearFDValueChanged, true);
            app.ICGearFD.Value = 7.75;
            app.stEditFieldLabel = uilabel(app.ICTab);
            app.stEditFieldLabel.Text = '1st';
            app.ICGear1 = uieditfield(app.ICTab, 'numeric');
            app.ICGear1.ValueChangedFcn = createCallbackFcn(app, @ICGear1ValueChanged, true);
            app.ICGear1.Value = 2.3;
            app.ndEditFieldLabel = uilabel(app.ICTab);
            app.ndEditFieldLabel.Text = '2nd';
            app.ICGear2 = uieditfield(app.ICTab, 'numeric');
            app.ICGear2.ValueChangedFcn = createCallbackFcn(app, @ICGear2ValueChanged, true);
            app.ICGear2.Value = 1.625;
            app.rdEditFieldLabel = uilabel(app.ICTab);
            app.rdEditFieldLabel.Text = '3rd';
            app.ICGear3 = uieditfield(app.ICTab, 'numeric');
            app.ICGear3.ValueChangedFcn = createCallbackFcn(app, @ICGear3ValueChanged, true);
            app.ICGear3.Value = 1.33;
            app.thEditFieldLabel = uilabel(app.ICTab);
            app.thEditFieldLabel.Text = '4th';
            app.ICGear4 = uieditfield(app.ICTab, 'numeric');
            app.ICGear4.ValueChangedFcn = createCallbackFcn(app, @ICGear4ValueChanged, true);
            app.ICGear4.Value = 1.12;
            app.thEditField_2Label = uilabel(app.ICTab);
            app.thEditField_2Label.Text = '5th';
            app.ICGear5 = uieditfield(app.ICTab, 'numeric');
            app.ICGear5.ValueChangedFcn = createCallbackFcn(app, @ICGear5ValueChanged, true);
            app.ICGear5.Value = 0.963;
            app.ConfiguredforTRX450Label = uilabel(app.ICTab);
            app.ConfiguredforTRX450Label.Text = 'Configured for TRX 450';
            app.Label_3 = uilabel(app.ICTab);
            app.Label_3.Text = '%';
            app.FuelDropDownLabel = uilabel(app.ICTab);
            app.FuelDropDownLabel.Text = 'Fuel';
            app.FuelDropDown = uidropdown(app.ICTab);
            app.FuelDropDown.Items = {'93', '95', 'E85'};
            app.FuelDropDown.Value = '93';
            app.ICMaxSpeed = uieditfield(app.ICTab, 'numeric');
            app.ICMaxSpeed.Editable = 'off';
            app.MaximumSpeedLabel = uilabel(app.ICTab);
            app.MaximumSpeedLabel.Text = {'Maximum Speed'; ''};
            app.kmhLabel = uilabel(app.ICTab);
            app.kmhLabel.Text = '[km/h]';
            app.DrivetrainEfficiencyEditFieldLabel = uilabel(app.ICTab);
            app.DrivetrainEfficiencyEditFieldLabel.Text = 'Drivetrain Efficiency';
            app.DREff = uieditfield(app.ICTab, 'numeric');
            app.DREff.Value = 95;
            app.MaximumRPMEditFieldLabel = uilabel(app.ICTab);
            app.MaximumRPMEditFieldLabel.Text = 'Maximum RPM';
            app.ICMaxRPM = uieditfield(app.ICTab, 'numeric');
            app.ICMaxRPM.ValueDisplayFormat = '%.0f';
            app.ICMaxRPM.Value = 10500;
            app.UIEngineAxis = uiaxes(app.ICTab);
            title(app.UIEngineAxis, 'Title')
            xlabel(app.UIEngineAxis, 'X')
            ylabel(app.UIEngineAxis, 'Y')
            app.EVTab = uitab(app.TabGroup2);
            app.EVTab.Title = 'EV';
            app.SaveCarButton = uibutton(app.CarDataTab, 'push');
            app.SaveCarButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCar, true);
            app.SaveCarButton.Text = 'Save Car';
            app.LoadCarButton = uibutton(app.CarDataTab, 'push');
            app.LoadCarButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCar, true);
            app.LoadCarButton.Text = 'Load Car';
            app.Image = uiimage(app.CarDataTab);
            app.Image.ImageSource = 'UTFR logo.png';
            app.v10Label = uilabel(app.CarDataTab);
            app.v10Label.Text = 'v1.0';
            app.SelectedCarDropDownLabel = uilabel(app.CarDataTab);
            app.SelectedCarDropDownLabel.Text = 'Selected Car';
            app.SelectedCarDropDown = uidropdown(app.CarDataTab);
            app.SelectedCarDropDown.Items = {};
            app.SelectedCarDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCar, true);
            app.SelectedCarDropDown.Value = {};
            app.CarNameEditFieldLabel = uilabel(app.CarDataTab);
            app.CarNameEditFieldLabel.Text = 'Car Name';
            app.CarNameEF = uieditfield(app.CarDataTab, 'text');
            app.Switch = uiswitch(app.CarDataTab, 'slider');
            app.Switch.Items = {'View Car', 'Edit Car'};
            app.Switch.ItemsData = [0 1];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @CarDataViewSwitch, true);
            app.Switch.Value = 1;
            app.TrackDataTab = uitab(app.MainTabGroup);
            app.TrackDataTab.Title = 'Track Data';
            app.TrackTable = uitable(app.TrackDataTab);
            app.TrackTable.ColumnName = {'Length [m]'; 'Radius [m]'};
            app.TrackTable.RowName = {};
            app.TrackTable.ColumnEditable = true;
            app.AddRowButton = uibutton(app.TrackDataTab, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrackRow, true);
            app.AddRowButton.Text = 'Add Row';
            app.DrawTrackButton = uibutton(app.TrackDataTab, 'push');
            app.DrawTrackButton.ButtonPushedFcn = createCallbackFcn(app, @DrawTrack, true);
            app.DrawTrackButton.Text = 'Draw Track';
            app.SaveTrackButton = uibutton(app.TrackDataTab, 'push');
            app.SaveTrackButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTrack, true);
            app.SaveTrackButton.Text = 'Save Track';
            app.LoadTrackButton = uibutton(app.TrackDataTab, 'push');
            app.LoadTrackButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrack, true);
            app.LoadTrackButton.Text = 'Load Track';
            app.TrackNameEditFieldLabel = uilabel(app.TrackDataTab);
            app.TrackNameEditFieldLabel.Text = 'Track Name:';
            app.TrackNameEditField = uieditfield(app.TrackDataTab, 'text');
            app.TrackNameEditField.ValueChangedFcn = createCallbackFcn(app, @TrackNameEditFieldValueChanged, true);
            app.DeleteRowButton = uibutton(app.TrackDataTab, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonPushed, true);
            app.DeleteRowButton.Text = 'Delete Row';
            app.TrackMap = uiaxes(app.TrackDataTab);
            title(app.TrackMap, 'Track Map')
            xlabel(app.TrackMap, 'X [m]')
            ylabel(app.TrackMap, 'Y [m]')
            app.SimulationParametersTab = uitab(app.MainTabGroup);
            app.SimulationParametersTab.Title = 'Simulation Parameters';
            app.SweepPanel = uipanel(app.SimulationParametersTab);
            app.SweepPanel.Title = '2 Parameter Sweeps';
            app.Parameter1DropDownLabel = uilabel(app.SweepPanel);
            app.Parameter1DropDownLabel.Text = 'Parameter 1';
            app.P1DD = uidropdown(app.SweepPanel);
            app.P1DD.Items = {'Mass', 'Track', 'Wheelbase', 'COG Height', 'Weight Distribution', 'Brake Bias', 'Tire Lat Coeff', 'Tire Long Coeff', 'CoL', 'CoD', 'Final Drive'};
            app.P1DD.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '', ''};
            app.P1DD.Value = '1';
            app.Parameter2DropDownLabel = uilabel(app.SweepPanel);
            app.Parameter2DropDownLabel.Text = 'Parameter 2';
            app.P2DD = uidropdown(app.SweepPanel);
            app.P2DD.Items = {'Mass', 'Track', 'Wheelbase', 'COG Height', 'Weight Distribution', 'Brake Bias', 'Tire Lat Coeff', 'Tire Long Coeff', 'CoL', 'CoD', 'Final Drive'};
            app.P2DD.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.P2DD.Value = '1';
            app.P1_1 = uieditfield(app.SweepPanel, 'numeric');
            app.P1_1.Limits = [0 Inf];
            app.P1_2 = uieditfield(app.SweepPanel, 'numeric');
            app.P1_2.Limits = [0 Inf];
            app.ToLabel = uilabel(app.SweepPanel);
            app.ToLabel.Text = 'To';
            app.HighLabel = uilabel(app.SweepPanel);
            app.HighLabel.Text = 'High';
            app.LowLabel = uilabel(app.SweepPanel);
            app.LowLabel.Text = 'Low';
            app.P2_1 = uieditfield(app.SweepPanel, 'numeric');
            app.P2_1.Limits = [0 Inf];
            app.P2_2 = uieditfield(app.SweepPanel, 'numeric');
            app.P2_2.Limits = [0 Inf];
            app.ToLabel_2 = uilabel(app.SweepPanel);
            app.ToLabel_2.Text = 'To';
            app.HighLabel_2 = uilabel(app.SweepPanel);
            app.HighLabel_2.Text = 'High';
            app.LowLabel_2 = uilabel(app.SweepPanel);
            app.LowLabel_2.Text = 'Low';
            app.ofPointsEditFieldLabel = uilabel(app.SweepPanel);
            app.ofPointsEditFieldLabel.Text = '# of Points';
            app.numP1 = uieditfield(app.SweepPanel, 'numeric');
            app.numP1.Limits = [0 Inf];
            app.ofPointsEditField_2Label = uilabel(app.SweepPanel);
            app.ofPointsEditField_2Label.Text = '# of Points';
            app.numP2 = uieditfield(app.SweepPanel, 'numeric');
            app.numP2.Limits = [0 Inf];
            app.Sweep2P = uibutton(app.SweepPanel, 'push');
            app.Sweep2P.ButtonPushedFcn = createCallbackFcn(app, @P2sweep, true);
            app.Sweep2P.Text = 'Sweep';
            app.SingleRunTrackListBoxLabel = uilabel(app.SimulationParametersTab);
            app.SingleRunTrackListBoxLabel.Text = 'Single Run Track';
            app.SingleRunTrackListBox = uilistbox(app.SimulationParametersTab);
            app.SingleRunTrackListBox.Items = {};
            app.SingleRunTrackListBox.ValueChangedFcn = createCallbackFcn(app, @AddTrackRow, true);
            app.SingleRunTrackListBox.Value = {};
            app.PointsCalculationsPanel = uipanel(app.SimulationParametersTab);
            app.PointsCalculationsPanel.Title = 'Points Calculations';
            app.EnduranceEditFieldLabel = uilabel(app.PointsCalculationsPanel);
            app.EnduranceEditFieldLabel.Text = 'Endurance';
            app.EnduranceEditField = uieditfield(app.PointsCalculationsPanel, 'numeric');
            app.AutocrossEditFieldLabel = uilabel(app.PointsCalculationsPanel);
            app.AutocrossEditFieldLabel.Text = 'Autocross';
            app.AutocrossEditField = uieditfield(app.PointsCalculationsPanel, 'numeric');
            app.SkidpadEditFieldLabel = uilabel(app.PointsCalculationsPanel);
            app.SkidpadEditFieldLabel.Text = 'Skidpad';
            app.SkidpadEditField = uieditfield(app.PointsCalculationsPanel, 'numeric');
            app.AccelEditFieldLabel = uilabel(app.PointsCalculationsPanel);
            app.AccelEditFieldLabel.Text = 'Accel';
            app.AccelEditField = uieditfield(app.PointsCalculationsPanel, 'numeric');
            app.EnterFastesttimesrecordedfortherelevanttracklayoutLabel = uilabel(app.PointsCalculationsPanel);
            app.EnterFastesttimesrecordedfortherelevanttracklayoutLabel.Text = 'Enter Fastest times recorded for the relevant track layout';
            app.EntermostrecentFastestTimeLabel = uilabel(app.PointsCalculationsPanel);
            app.EntermostrecentFastestTimeLabel.Text = 'Enter most recent Fastest Time';
            app.EnduranceTrackDropDownLabel = uilabel(app.PointsCalculationsPanel);
            app.EnduranceTrackDropDownLabel.Text = 'Endurance Track';
            app.EnduranceTrackDropDown = uidropdown(app.PointsCalculationsPanel);
            app.AutocrossTrackDropDownLabel = uilabel(app.PointsCalculationsPanel);
            app.AutocrossTrackDropDownLabel.Text = 'Autocross Track';
            app.AutocrossTrackDropDown = uidropdown(app.PointsCalculationsPanel);
            app.secLabel = uilabel(app.PointsCalculationsPanel);
            app.secLabel.Text = 'sec';
            app.secLabel_2 = uilabel(app.PointsCalculationsPanel);
            app.secLabel_2.Text = 'sec';
            app.secLabel_3 = uilabel(app.PointsCalculationsPanel);
            app.secLabel_3.Text = 'sec';
            app.secLabel_4 = uilabel(app.PointsCalculationsPanel);
            app.secLabel_4.Text = 'sec';
            app.SaveRunButton = uibutton(app.SimulationParametersTab, 'push');
            app.SaveRunButton.Text = 'Save Run';
            app.LoadedCarsListBoxLabel = uilabel(app.SimulationParametersTab);
            app.LoadedCarsListBoxLabel.Text = 'Loaded Cars';
            app.LoadedCarsListBox = uilistbox(app.SimulationParametersTab);
            app.LoadedCarsListBox.Items = {};
            app.LoadedCarsListBox.ValueChangedFcn = createCallbackFcn(app, @CarSelection, true);
            app.LoadedCarsListBox.Value = {};
            app.SingleRunButton = uibutton(app.SimulationParametersTab, 'push');
            app.SingleRunButton.ButtonPushedFcn = createCallbackFcn(app, @SingleRun, true);
            app.SingleRunButton.Text = 'Single Run';
            app.UpdateDataButton = uibutton(app.SimulationParametersTab, 'push');
            app.UpdateDataButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDataButtonPushed, true);
            app.UpdateDataButton.Text = 'Update Data';
            app.ResultsAnalysisTab = uitab(app.MainTabGroup);
            app.ResultsAnalysisTab.Title = 'Results Analysis';
            app.ViewOptionsPanel = uipanel(app.ResultsAnalysisTab);
            app.ViewOptionsPanel.Title = 'View Options';
            app.XAxisDropDownLabel = uilabel(app.ViewOptionsPanel);
            app.XAxisDropDownLabel.Text = 'X Axis';
            app.XAxisDropDown = uidropdown(app.ViewOptionsPanel);
            app.XAxisDropDown.Items = {'Distance', 'Time', 'Speed', 'Lat. Accel', 'Long. Accel', 'RPM', 'Gear'};
            app.XAxisDropDown.ItemsData = {'distance', 'time', 'velocity', 'along', 'alat', 'rpm', 'gear'};
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDataChange, true);
            app.XAxisDropDown.Enable = 'off';
            app.XAxisDropDown.Value = 'distance';
            app.YAxisDropDownLabel = uilabel(app.ViewOptionsPanel);
            app.YAxisDropDownLabel.Text = 'Y Axis';
            app.YAxisDropDown = uidropdown(app.ViewOptionsPanel);
            app.YAxisDropDown.Items = {'Distance', 'Time', 'Speed', 'Lat. Accel', 'Long. Accel', 'RPM', 'Gear'};
            app.YAxisDropDown.ItemsData = {'distance', 'time', 'velocity', 'along', 'alat', 'rpm', 'gear'};
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDataChange, true);
            app.YAxisDropDown.Enable = 'off';
            app.YAxisDropDown.Value = 'distance';
            app.ZAxisDropDownLabel = uilabel(app.ViewOptionsPanel);
            app.ZAxisDropDownLabel.Text = 'Z Axis';
            app.ZAxisDropDown = uidropdown(app.ViewOptionsPanel);
            app.ZAxisDropDown.Items = {'Distance', 'Time', 'Speed', 'Lat. Accel', 'Long. Accel', 'RPM', 'Gear'};
            app.ZAxisDropDown.ItemsData = {'distance', 'time', 'velocity', 'along', 'alat', 'rpm', 'gear'};
            app.ZAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @ZAxisDataChange, true);
            app.ZAxisDropDown.Enable = 'off';
            app.ZAxisDropDown.Value = 'distance';
            app.ListBoxLabel = uilabel(app.ViewOptionsPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.ViewOptionsPanel);
            app.ListBox.Items = {'Line 2D', 'Track 2D', 'Gradient 3D', 'ISO 3D'};
            app.ListBox.ItemsData = {'1', '2', '3', '4'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ResultsPlotAxisControl, true);
            app.ListBox.Value = {};
            app.XAxisUnits = uidropdown(app.ViewOptionsPanel);
            app.XAxisUnits.Items = {};
            app.XAxisUnits.Enable = 'off';
            app.XAxisUnits.Value = {};
            app.YAxisUnits = uidropdown(app.ViewOptionsPanel);
            app.YAxisUnits.Items = {};
            app.YAxisUnits.Enable = 'off';
            app.YAxisUnits.Value = {};
            app.ZAxisUnits = uidropdown(app.ViewOptionsPanel);
            app.ZAxisUnits.Items = {};
            app.ZAxisUnits.Enable = 'off';
            app.ZAxisUnits.Value = {};
            app.LoadedDatasetsListBoxLabel = uilabel(app.ResultsAnalysisTab);
            app.LoadedDatasetsListBoxLabel.Text = 'Loaded Datasets';
            app.LDSlistbox = uilistbox(app.ResultsAnalysisTab);
            app.LDSlistbox.Items = {};
            app.LDSlistbox.ValueChangedFcn = createCallbackFcn(app, @SelectData, true);
            app.LDSlistbox.Value = {};
            app.LoadDataButton = uibutton(app.ResultsAnalysisTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SaveGraphButton = uibutton(app.ResultsAnalysisTab, 'push');
            app.SaveGraphButton.Text = 'Save Graph';
            app.ComparisonsPanel = uipanel(app.ResultsAnalysisTab);
            app.ComparisonsPanel.Title = 'Comparisons';
            app.PlotGraphButton = uibutton(app.ResultsAnalysisTab, 'push');
            app.PlotGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsPlot, true);
            app.PlotGraphButton.Text = 'Plot Graph';
            app.UIAxes2 = uiaxes(app.ResultsAnalysisTab);
            title(app.UIAxes2, 'Dist vs Velocity')
            xlabel(app.UIAxes2, 'Distance')
            ylabel(app.UIAxes2, 'Velocity')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = utfr_lapsim_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end